classdef pieceAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VisorBieleUIFigure  matlab.ui.Figure
        Toolbar             matlab.ui.container.Toolbar
        bBoxTool            matlab.ui.container.toolbar.PushTool
        appLayout           matlab.ui.container.GridLayout
        settingsLayout      matlab.ui.container.GridLayout
        resultsGroup        matlab.ui.container.TabGroup
        Tab                 matlab.ui.container.Tab
        canvas              matlab.ui.control.UIAxes
        configTabs          matlab.ui.container.TabGroup
        loadFiles           matlab.ui.container.Tab
        loadFilesLayout     matlab.ui.container.GridLayout
        loadImageButton     matlab.ui.control.Button
        previewImage        matlab.ui.control.Image
        loadSVGButton       matlab.ui.control.Button
        previewSVG          matlab.ui.control.Image
    end

 
    properties (Access = private)
        canvasWrapper;
        previewImageWrapper;
        previewSVGWrapper;
        
        imageController;
        svgController;

        modelImage;
        modelSVG;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Wrapper components
            app.canvasWrapper = viewWrapper.Canvas(app.canvas);
            app.previewImageWrapper = viewWrapper.previewFiles.Image(app.previewImage);
            app.previewSVGWrapper = viewWrapper.previewFiles.SVG(app.previewSVG);

            % Create models
            app.modelImage = models.Image();
            app.modelSVG   = models.SVG();

            % Create controllers
            app.imageController = controllers.ImageController(app.modelImage, ...
                app.previewImageWrapper, ...
                app.canvasWrapper);
            app.svgController = controllers.SVGController(app.modelSVG, ...
                app.previewSVGWrapper, ...
                app.canvasWrapper);
        end

        % Button pushed function: loadImageButton
        function loadImageButtonPushed(app, event)
            % Allowed files
            [file, path] = uigetfile({'*.png;*.jpg;*.jpeg;', ...
                'Imágenes (*.png, *.jpg, *.jpeg)'}, ...
                'Selecciona una imagen');

            % Not selected file
            if isequal(file, 0)
                return;
            end
            
            app.imageController.loadImageFromDialog(path, file);
        end

        % Image clicked function: previewImage
        function previewImageClicked(app, event)
            app.imageController.previewImageOnCanva();
        end

        % Button pushed function: loadSVGButton
        function loadSVGButtonPushed(app, event)
            % Allowed files
            [file, path] = uigetfile({'*.svg', 'Archivos SVG (*.svg)'}, ...
                                                 'Selecciona un archivo SVG');
            % Not selected file
            if isequal(file, 0)
                return;
            end
            
            app.svgController.loadSVGFromDialog(path, file);
        end

        % Image clicked function: previewSVG
        function previewSVGClicked(app, event)
            app.svgController.previewSVGOnCanva();
        end

        % Button down function: canvas
        function canvasButtonDown(app, event)
            if strcmp(app.VisorBieleUIFigure.SelectionType, 'normal')  % botón izquierdo

                % Llamar sin 'Position' → permite arrastrar con el ratón
                drawrectangle(app.canvas, ...
                    'Color', 'b', ...
                    'LineWidth', 1.5, ...
                    'FaceAlpha', 0);  % sin relleno
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.VisorBieleUIFigure = uifigure('Visible', 'off');
            app.VisorBieleUIFigure.Name = 'Visor Biele';
            app.VisorBieleUIFigure.Icon = fullfile(pathToMLAPP, 'picturesApp', 'logoBiele.png');
            app.Toolbar = uitoolbar(app.VisorBieleUIFigure);
            app.bBoxTool = uipushtool(app.Toolbar);
            app.bBoxTool.Tooltip = {'Dibujar BoundingBox sobre la pieza'};
            app.bBoxTool.Icon = fullfile(pathToMLAPP, 'picturesApp', 'bBoxToolIcon.png');
            app.appLayout = uigridlayout(app.VisorBieleUIFigure);
            app.configTabs = uitabgroup(app.appLayout);
            app.loadFiles = uitab(app.configTabs);
            app.loadFiles.Title = 'Botonera';
            app.loadFilesLayout = uigridlayout(app.loadFiles);
            app.previewSVG = uiimage(app.loadFilesLayout);
            app.previewSVG.ImageClickedFcn = createCallbackFcn(app, @previewSVGClicked, true);
            app.loadSVGButton = uibutton(app.loadFilesLayout, 'push');
            app.loadSVGButton.ButtonPushedFcn = createCallbackFcn(app, @loadSVGButtonPushed, true);
            app.loadSVGButton.Text = 'Cargar SVG';
            app.previewImage = uiimage(app.loadFilesLayout);
            app.previewImage.ImageClickedFcn = createCallbackFcn(app, @previewImageClicked, true);
            app.loadImageButton = uibutton(app.loadFilesLayout, 'push');
            app.loadImageButton.ButtonPushedFcn = createCallbackFcn(app, @loadImageButtonPushed, true);
            app.loadImageButton.Text = 'Cargar imagen';
            app.settingsLayout = uigridlayout(app.appLayout);
            app.canvas = uiaxes(app.settingsLayout);
            title(app.canvas, 'Title')
            xlabel(app.canvas, 'X')
            ylabel(app.canvas, 'Y')
            zlabel(app.canvas, 'Z')
            app.canvas.ButtonDownFcn = createCallbackFcn(app, @canvasButtonDown, true);
            app.resultsGroup = uitabgroup(app.settingsLayout);
            app.Tab = uitab(app.resultsGroup);
            app.Tab.Title = 'Tab';
            % Show the figure after all components are created
            app.VisorBieleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pieceAnalyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VisorBieleUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VisorBieleUIFigure)
        end
    end
end